classdef SAUCY_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAUCYUIFigure                   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutSAUCYMenu                  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataFilesTab                    matlab.ui.container.Tab
        FileTree                        matlab.ui.container.Tree
        FileTable                       matlab.ui.control.Table
        UpdateFileStatusButton          matlab.ui.control.Button
        DoBatchButton                   matlab.ui.control.Button
        ExportSpikesButton              matlab.ui.control.Button
        CheckFileButton                 matlab.ui.control.Button
        IntanrhdCheckBox                matlab.ui.control.CheckBox
        SelectfileformatsforimportLabel  matlab.ui.control.Label
        MATLABmatCheckBox               matlab.ui.control.CheckBox
        UpdateListButton                matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        ProjectExperimentNameEditFieldLabel  matlab.ui.control.Label
        ProjectExperimentNameEditField  matlab.ui.control.EditField
        WaveformsTab                    matlab.ui.container.Tab
        ActiveFilePanel                 matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        UIAxes_15                       matlab.ui.control.UIAxes
        UIAxes_16                       matlab.ui.control.UIAxes
        UIAxes_17                       matlab.ui.control.UIAxes
        ChannelConfigSettingsPanel      matlab.ui.container.Panel
        ShowChansDropDownLabel          matlab.ui.control.Label
        ShowChansDropDown               matlab.ui.control.DropDown
        ChanConfigDropDownLabel         matlab.ui.control.Label
        ChanConfigDropDown              matlab.ui.control.DropDown
        CommonCHEditFieldLabel          matlab.ui.control.Label
        CommonCHEditField               matlab.ui.control.NumericEditField
        ChannelSubtractionsTextAreaLabel  matlab.ui.control.Label
        ChannelSubtractionsTextArea     matlab.ui.control.TextArea
        UpdateButton_3                  matlab.ui.control.Button
        DigitalFilterSettingsPanel      matlab.ui.container.Panel
        HighCutOffHzEditFieldLabel      matlab.ui.control.Label
        HighCutOffHzEditField           matlab.ui.control.NumericEditField
        NotchFilter60HzCheckBox         matlab.ui.control.CheckBox
        ApplyButton                     matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        LowCutOffHzEditFieldLabel       matlab.ui.control.Label
        LowCutOffHzEditField            matlab.ui.control.NumericEditField
        DetectionTab                    matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        DetectionSettingsPanel          matlab.ui.container.Panel
        AlignmentDropDownLabel          matlab.ui.control.Label
        AlignmentDropDown               matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        ThresholdDropDown               matlab.ui.control.DropDown
        SDFactorEditFieldLabel          matlab.ui.control.Label
        SDFactorEditField               matlab.ui.control.NumericEditField
        ThresholdforPeaksEditFieldLabel  matlab.ui.control.Label
        ThresholdforPeaksEditField      matlab.ui.control.NumericEditField
        ThresholdforNoiseEditFieldLabel  matlab.ui.control.Label
        ThresholdforNoiseEditField      matlab.ui.control.NumericEditField
        DoDetectButton                  matlab.ui.control.Button
        ImportTimesButton               matlab.ui.control.Button
        ClusteringTab                   matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        ClusteringOptimizationSettingsPanel  matlab.ui.container.Panel
        NumberofClustersNeighborsEditFieldLabel  matlab.ui.control.Label
        NumberofClustersNeighborsEditField  matlab.ui.control.NumericEditField
        DoClusteringButton              matlab.ui.control.Button
        ClusteringMethodDropDownLabel   matlab.ui.control.Label
        ClusteringMethodDropDown        matlab.ui.control.DropDown
        OptimizationMethodDropDownLabel  matlab.ui.control.Label
        OptimizationMethodDropDown      matlab.ui.control.DropDown
        TrackingTab                     matlab.ui.container.Tab
        ClusterTrackingPanel            matlab.ui.container.Panel
        UIAxes9_4                       matlab.ui.control.UIAxes
        UIAxes9_5                       matlab.ui.control.UIAxes
        CentersCheckBox                 matlab.ui.control.CheckBox
        EllipsesCheckBox                matlab.ui.control.CheckBox
        LabelsCheckBox                  matlab.ui.control.CheckBox
        UpdateButton                    matlab.ui.control.Button
        SelectClustersListBoxLabel      matlab.ui.control.Label
        SelectClustersListBox           matlab.ui.control.ListBox
        ClusterComparisonPanel          matlab.ui.container.Panel
        UIAxes9_6                       matlab.ui.control.UIAxes
        UIAxes9_7                       matlab.ui.control.UIAxes
        CentersCheckBox_2               matlab.ui.control.CheckBox
        EllipsesCheckBox_2              matlab.ui.control.CheckBox
        LabelsCheckBox_2                matlab.ui.control.CheckBox
        UpdateButton_2                  matlab.ui.control.Button
        Axis1DropDownLabel              matlab.ui.control.Label
        Axis1DropDown                   matlab.ui.control.DropDown
        Axis2DropDownLabel              matlab.ui.control.Label
        Axis2DropDown                   matlab.ui.control.DropDown
        LinkAxesScaleCheckBox           matlab.ui.control.CheckBox
        VerificationTab                 matlab.ui.container.Tab
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5_3                       matlab.ui.control.UIAxes
        UIAxes5_4                       matlab.ui.control.UIAxes
        UIAxes5_5                       matlab.ui.control.UIAxes
        xAxisDropDownLabel              matlab.ui.control.Label
        xAxisDropDown                   matlab.ui.control.DropDown
        yAxisDropDownLabel              matlab.ui.control.Label
        yAxisDropDown                   matlab.ui.control.DropDown
        ResultTab                       matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        DataFileListBoxLabel            matlab.ui.control.Label
        DataFileListBox                 matlab.ui.control.ListBox
        UpdatePlotButton                matlab.ui.control.Button
        ThresholdCheckBox               matlab.ui.control.CheckBox
        SpikeRasterCheckBox             matlab.ui.control.CheckBox
        ISIViolationsCheckBox           matlab.ui.control.CheckBox
        ClustersListBoxLabel            matlab.ui.control.Label
        ClustersListBox                 matlab.ui.control.ListBox
        StatusPanel                     matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        StatusText                      matlab.ui.control.Label
    end


    properties (Access = private)
        FileTreeNodes % Handles to tree nodes that are files in FileTree
        FileTreeRoot % Handle to tree nodes that is current working directory in FileTree
        SelectedFileTableRow % Keeps track of selected file names
        SelectedFileTableCell % Keeps track of which "Active" box is checked -- only 1 allowed
        ProjectName % Name of project/experiment
    end

    properties (Access = public)
        SaucyData = {} % Cell array of SAUCY objects
    end

    methods (Access = private)
    
        function results = update_file_tree(app, rootDir)
            disp('Finding files...');
            
            for nodeIx=1:length(app.FileTreeNodes)
                delete(app.FileTreeNodes(nodeIx)); 
            end
            
            if nargin == 1
                dirPath = pwd;
            else
                dirPath = rootDir;
            end
            
            disp(app.FileTreeRoot);
            try
                app.FileTreeRoot.Text;
                rootNode = app.FileTreeRoot;
            catch
                rootDir = split(dirPath, '\');
                rootNode = uitreenode(app.FileTree, 'Text', rootDir{end}, 'NodeData', []);
            end
            
            files = dir(dirPath);
            fileNodes = [];
            
            showExts = {};
            if app.IntanrhdCheckBox.Value
                showExts{end+1} = ['rhd'];
            end
            if app.MATLABmatCheckBox.Value
                showExts{end+1} = ['mat'];
            end
            
            disp('Updating file tree...');
            for fileIx = 1:length(files)
                if strcmp(files(fileIx).name(1),'.')
                    continue
                end
                if files(fileIx).isdir == 1
                    uitreenode(rootNode, 'Text', files(fileIx).name, 'NodeData', files(fileIx).name);
                else
                    fname = files(fileIx).name;
                    ext = split(fname, '.');
                    
                    if cell2mat(strfind(showExts, ext(end))) > 0
                         fileNodes(end+1) = uitreenode(rootNode, 'Text', fname, 'NodeData', fname);
                    end
                end
            end
            
            app.FileTreeRoot = rootNode;
            app.FileTreeNodes = fileNodes;
            expand(rootNode);
            
            disp(['Ready...',newline]);
        end
        
        function add_file_to_table(app, fileTreeNode)
            disp('Adding file to table');
            fileTableData = get(app.FileTable, 'Data');
            newRow = {fileTreeNode.Text, false, false, 'N/A', 0.0, 'N/A'};
            if length(fileTableData) == 0
                set(app.FileTable, 'Data', newRow);
            else
                set(app.FileTable, 'Data', [fileTableData; newRow]);
            end
        end
        
        function update_waveforms(app)
            disp('Updating waveform plots...');
            saucy = app.SaucyData{1};
            t_amp = saucy.raw_data.t_amplifier;
            amp_data_filt = saucy.data.amplifier_data_filt;
            
            chanCount = app.ShowChansDropDown.Value;
            rows = 4;
            cols = 4;
            switch chanCount
                case 1
                    cols = 1;
                    rows = 1;
                case 2
                    cols = 1;
                    rows = 2;
                case 4
                    cols = 2;
                    rows = 2;
                case 8
                    cols = 2;
                    rows = 4;
                case 16
                    cols = 4;
                    rows = 4;
            end
            
            ShowChannelCount = app.ShowChansDropDown.Value;
            
            axes = app.ActiveFilePanel.Children;
            axesCount = length(axes);
            
            axes = build_axes_grid(app, app.ActiveFilePanel, rows, cols);
            
            for axisIx=1:length(axes)
                plot(axes{axisIx}, t_amp, amp_data_filt(axisIx,:));
            end
            
            disp(['Ready...',newline]);
        end
        
        function results = build_axes_grid(app, parent, cols, rows, wspace, hspace)
            childrenAxes = get(parent, 'Children');
            for childIx=1:length(childrenAxes)
                if isa(childrenAxes(childIx), 'matlab.ui.control.UIAxes')
                    delete(childrenAxes(childIx));
                end
            end
            
            if nargin == 4
                wspace = 20;
                hspace = 20;
            end
            
            ParentPosition = get(parent, 'Position');
            widthParent = ParentPosition(3);
            heightParent = ParentPosition(4);
            
            axesWidth = (widthParent - (cols+1)*wspace)/cols;
            axesHeight = (heightParent - (rows+1)*hspace)/cols;
            
            results = {};
            for i=1:(rows*cols)
                modCols = mod(i+cols-1,cols)+1;
                modRows = floor((i-1)/cols)+1;
                
                xPos = modCols*wspace + (modCols-1)*axesWidth;
                yPos = heightParent - (modRows*(hspace + axesHeight)) - 10; % Add extra padding above top row for titles
                
                %disp(['Axis ', num2str(i)]);
                %disp([modCols modRows]);
                %disp([xPos yPos axesWidth axesHeight]);
                name = strcat(['Ch',num2str(i,'%02.f')]);
                ax = uiaxes(parent, 'Position', [xPos yPos axesWidth axesHeight]);
                set(ax.Title, 'String', name);
                results{end+1} = ax;
            end
        end
        
        function update_chan_config_settings(app)
            panelChildren = app.ChannelConfigSettingsPanel.Children;
            
            switch app.ChanConfigDropDown.Value
                case 'Subtraction'
                    app.ShowChansDropDown.Enable = 'on';
                    app.ChanConfigDropDown.Enable = 'on';
                    app.CommonCHEditField.Enable = 'on';
                    app.ChannelSubtractionsTextArea.Enable = 'off';
                    for c=1:length(panelChildren)
                        if isa(panelChildren(c),'matlab.ui.control.Label')
                            if strcmp(panelChildren(c).Text, 'Show Chans') | ...
                                    strcmp(panelChildren(c).Text, 'Chan Config') | ...
                                    strcmp(panelChildren(c).Text, 'Common CH')
                                panelChildren(c).Enable = 'on';
                            elseif strcmp(panelChildren(c).Text, 'Channel Subtractions')
                                panelChildren(c).Enable = 'off';
                            end
                        end
                    end
                case 'Custom'
                    app.ShowChansDropDown.Enable = 'on';
                    app.ChanConfigDropDown.Enable = 'on';
                    app.CommonCHEditField.Enable = 'off';
                    app.ChannelSubtractionsTextArea.Enable = 'on';
                    for c=1:length(panelChildren)
                        if isa(panelChildren(c),'matlab.ui.control.Label')
                            if strcmp(panelChildren(c).Text, 'Show Chans') | ...
                                    strcmp(panelChildren(c).Text, 'Chan Config') | ...
                                    strcmp(panelChildren(c).Text, 'Channel Subtractions')
                                panelChildren(c).Enable = 'on';
                            elseif strcmp(panelChildren(c).Text, 'Common CH')
                                panelChildren(c).Enable = 'off';
                            end
                        end
                    end
                otherwise     
                    app.ShowChansDropDown.Enable = 'on';
                    app.ChanConfigDropDown.Enable = 'on';
                    app.CommonCHEditField.Enable = 'off';
                    app.ChannelSubtractionsTextArea.Enable = 'off';
                    for c=1:length(panelChildren)
                        if isa(panelChildren(c),'matlab.ui.control.Label')
                            if strcmp(panelChildren(c).Text, 'Show Chans') | ...
                                    strcmp(panelChildren(c).Text, 'Chan Config')
                                panelChildren(c).Enable = 'on';
                            elseif strcmp(panelChildren(c).Text, 'Common CH') | ...
                                    strcmp(panelChildren(c).Text, 'Channel Subtractions')
                                panelChildren(c).Enable = 'off';
                            end
                        end
                    end
            end
            app.UpdateButton_3.Enable = 'on';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Code to increase the size of the GUI window
            % This code does not change the size of the tabs...
            %set(app.SAUCYUIFigure, 'Position', [50 50 1500 900]);
            %movegui(app.SAUCYUIFigure, 'center');
            
            disp('------------------------------------------');
            disp('===== ===== STARTING SAUCY GUI ===== =====');
            disp('------------------------------------------');
            
            update_file_tree(app);
            set(app.FileTable, 'ColumnEditable', [false true false false false false]);
            set(app.FileTable, 'ColumnWidth', {'auto', 'auto', 'auto', 'auto', 'auto', 'auto'});
            disp(['Ready...',newline]);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_file_tree(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            disp('Updating file table');selected = app.FileTree.SelectedNodes;
            
            for selectIx = 1:length(selected)
                add_file_to_table(app, selected(selectIx));
            end
            
            name = split(selected(1).Text, '.');
            
            app.ProjectName = name{1};
            set(app.ProjectExperimentNameEditField, 'Value', name{1});
            disp(['Ready...',newline]);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            disp('Updating file table');
            treeNodes = app.FileTreeRoot.Children;
            
            for nodeIx = 1:length(treeNodes)
                add_file_to_table(app, treeNodes(nodeIx));
            end
            disp(['Ready...',newline]);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            disp('Updating file table');
            data = get(app.FileTable, 'Data')
            data(app.SelectedFileTableRow(1:end,1),:) = [];
            set(app.FileTable, 'Data', data);
            disp(['Ready...',newline]);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            disp('Updating file table');
            set(app.FileTable, 'Data', {});
            disp(['Ready...',newline]);
        end

        % Cell selection callback: FileTable
        function FileTableCellSelection(app, event)
            for rowIx = 1:size(event.Indices,1)
                if event.Indices(rowIx,2) == 1
                    app.SelectedFileTableRow = [app.SelectedFileTableRow; event.Indices(rowIx,:)];
                end
            end 
        end

        % Cell edit callback: FileTable
        function FileTableCellEdit(app, event)
            data = get(app.FileTable, 'Data');
            ixs = event.Indices;
            app.SelectedFileTableCell = ixs;
            
            for ix = 1:size(data,1)
                data(ix,2) = {false};
            end
            data(ixs(1), ixs(2)) = {true};
            set(app.FileTable, 'Data', data);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            disp('Loading file...');
            
            TableData = get(app.FileTable, 'Data');
            ix = find(cell2mat(TableData(:,2)) == 1);
            fileName = cell2mat(TableData(ix,1));
            disp(fileName);
            saucyName = split(fileName, '.');
            
            disp('Loading SAUCY data...');
            saucy = SAUCY(saucyName(1));
            saucy.load_data(fileName);
            
            disp('Filtering data...');
            saucy.filter_data();
            
            app.SaucyData{end+1} = saucy;
            
            app.update_waveforms();
            app.update_chan_config_settings();
            
            app.TabGroup.SelectedTab = app.WaveformsTab;
            disp(['Ready...',newline]);
        end

        % Value changed function: ChanConfigDropDown
        function ChanConfigDropDownValueChanged(app, event)
            app.update_chan_config_settings();           
        end

        % Value changed function: ShowChansDropDown
        function ShowChansDropDownValueChanged(app, event)
            % PASS
        end

        % Button pushed function: UpdateButton_3
        function UpdateButton_3Pushed(app, event)
            app.update_waveforms();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAUCYUIFigure = uifigure;
            app.SAUCYUIFigure.Name = 'SAUCY';
            app.FileMenu = uimenu(app.SAUCYUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.SettingsMenu = uimenu(app.FileMenu);
            app.SettingsMenu.Separator = 'on';
            app.SettingsMenu.Accelerator = 'G';
            app.SettingsMenu.Text = 'Settings';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.SAUCYUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutSAUCYMenu = uimenu(app.HelpMenu);
            app.AboutSAUCYMenu.Text = 'About SAUCY';
            app.TabGroup = uitabgroup(app.SAUCYUIFigure);
            app.DataFilesTab = uitab(app.TabGroup);
            app.DataFilesTab.Title = 'Data Files';
            app.FileTree = uitree(app.DataFilesTab);
            app.FileTree.Multiselect = 'on';
            app.FileTable = uitable(app.DataFilesTab);
            app.FileTable.ColumnName = {'File'; 'Active'; 'Batch'; 'Params'; 'p-value'; 'Quality'};
            app.FileTable.RowName = {};
            app.FileTable.CellEditCallback = createCallbackFcn(app, @FileTableCellEdit, true);
            app.FileTable.CellSelectionCallback = createCallbackFcn(app, @FileTableCellSelection, true);
            app.UpdateFileStatusButton = uibutton(app.DataFilesTab, 'push');
            app.UpdateFileStatusButton.Enable = 'off';
            app.UpdateFileStatusButton.Text = 'Update File Status';
            app.DoBatchButton = uibutton(app.DataFilesTab, 'push');
            app.DoBatchButton.Enable = 'off';
            app.DoBatchButton.Text = 'Do Batch';
            app.ExportSpikesButton = uibutton(app.DataFilesTab, 'push');
            app.ExportSpikesButton.Enable = 'off';
            app.ExportSpikesButton.Text = 'Export Spikes';
            app.CheckFileButton = uibutton(app.DataFilesTab, 'push');
            app.CheckFileButton.Enable = 'off';
            app.CheckFileButton.Text = 'Check File';
            app.IntanrhdCheckBox = uicheckbox(app.DataFilesTab);
            app.IntanrhdCheckBox.Text = 'Intan (*.rhd)';
            app.IntanrhdCheckBox.Value = true;
            app.SelectfileformatsforimportLabel = uilabel(app.DataFilesTab);
            app.SelectfileformatsforimportLabel.Text = 'Select file formats for import:';
            app.MATLABmatCheckBox = uicheckbox(app.DataFilesTab);
            app.MATLABmatCheckBox.Text = 'MATLAB (*.mat)';
            app.UpdateListButton = uibutton(app.DataFilesTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.Button = uibutton(app.DataFilesTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>>';
            app.Button_2 = uibutton(app.DataFilesTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.Button_3 = uibutton(app.DataFilesTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<';
            app.Button_4 = uibutton(app.DataFilesTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '<<';
            app.LoadFileButton = uibutton(app.DataFilesTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.ProjectExperimentNameEditFieldLabel = uilabel(app.DataFilesTab);
            app.ProjectExperimentNameEditFieldLabel.Text = 'Project/Experiment Name';
            app.ProjectExperimentNameEditField = uieditfield(app.DataFilesTab, 'text');
            app.WaveformsTab = uitab(app.TabGroup);
            app.WaveformsTab.Title = 'Waveforms';
            app.ActiveFilePanel = uipanel(app.WaveformsTab);
            app.ActiveFilePanel.Title = 'Active File:';
            app.UIAxes = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes, 'Ch01')
            app.UIAxes_2 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_2, 'Ch05')
            app.UIAxes_3 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_3, 'Ch09')
            app.UIAxes_5 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_5, 'Ch02')
            app.UIAxes_6 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_6, 'Ch03')
            app.UIAxes_7 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_7, 'Ch04')
            app.UIAxes_8 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_8, 'Ch06')
            app.UIAxes_9 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_9, 'Ch07')
            app.UIAxes_10 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_10, 'Ch08')
            app.UIAxes_11 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_11, 'Ch10')
            app.UIAxes_12 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_12, 'Ch11')
            app.UIAxes_13 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_13, 'Ch12')
            app.UIAxes_14 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_14, 'Ch13')
            app.UIAxes_15 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_15, 'Ch14')
            app.UIAxes_16 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_16, 'Ch15')
            app.UIAxes_17 = uiaxes(app.ActiveFilePanel);
            title(app.UIAxes_17, 'Ch16')
            app.ChannelConfigSettingsPanel = uipanel(app.WaveformsTab);
            app.ChannelConfigSettingsPanel.Title = 'Channel Config Settings';
            app.ShowChansDropDownLabel = uilabel(app.ChannelConfigSettingsPanel);
            app.ShowChansDropDownLabel.Enable = 'off';
            app.ShowChansDropDownLabel.Text = 'Show Chans';
            app.ShowChansDropDown = uidropdown(app.ChannelConfigSettingsPanel);
            app.ShowChansDropDown.Items = {'1', '2', '4', '8', '16', '32', '64', '96', '128'};
            app.ShowChansDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowChansDropDownValueChanged, true);
            app.ShowChansDropDown.Enable = 'off';
            app.ShowChansDropDown.Value = '16';
            app.ChanConfigDropDownLabel = uilabel(app.ChannelConfigSettingsPanel);
            app.ChanConfigDropDownLabel.Enable = 'off';
            app.ChanConfigDropDownLabel.Text = 'Chan Config';
            app.ChanConfigDropDown = uidropdown(app.ChannelConfigSettingsPanel);
            app.ChanConfigDropDown.Items = {'Unipolar', 'Bipolar', 'Subtraction', 'Custom'};
            app.ChanConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ChanConfigDropDownValueChanged, true);
            app.ChanConfigDropDown.Enable = 'off';
            app.ChanConfigDropDown.Value = 'Unipolar';
            app.CommonCHEditFieldLabel = uilabel(app.ChannelConfigSettingsPanel);
            app.CommonCHEditFieldLabel.Enable = 'off';
            app.CommonCHEditFieldLabel.Text = 'Common CH';
            app.CommonCHEditField = uieditfield(app.ChannelConfigSettingsPanel, 'numeric');
            app.CommonCHEditField.Enable = 'off';
            app.ChannelSubtractionsTextAreaLabel = uilabel(app.ChannelConfigSettingsPanel);
            app.ChannelSubtractionsTextAreaLabel.Enable = 'off';
            app.ChannelSubtractionsTextAreaLabel.Text = 'Channel Subtractions';
            app.ChannelSubtractionsTextArea = uitextarea(app.ChannelConfigSettingsPanel);
            app.ChannelSubtractionsTextArea.Enable = 'off';
            app.UpdateButton_3 = uibutton(app.ChannelConfigSettingsPanel, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_3Pushed, true);
            app.UpdateButton_3.Enable = 'off';
            app.UpdateButton_3.Text = 'Update';
            app.DigitalFilterSettingsPanel = uipanel(app.WaveformsTab);
            app.DigitalFilterSettingsPanel.Title = 'Digital Filter Settings';
            app.HighCutOffHzEditFieldLabel = uilabel(app.DigitalFilterSettingsPanel);
            app.HighCutOffHzEditFieldLabel.Enable = 'off';
            app.HighCutOffHzEditFieldLabel.Text = 'High Cut-Off (Hz)';
            app.HighCutOffHzEditField = uieditfield(app.DigitalFilterSettingsPanel, 'numeric');
            app.HighCutOffHzEditField.Enable = 'off';
            app.HighCutOffHzEditField.Value = 7500;
            app.NotchFilter60HzCheckBox = uicheckbox(app.DigitalFilterSettingsPanel);
            app.NotchFilter60HzCheckBox.Enable = 'off';
            app.NotchFilter60HzCheckBox.Text = 'Notch Filter (60 Hz)';
            app.NotchFilter60HzCheckBox.Value = true;
            app.ApplyButton = uibutton(app.DigitalFilterSettingsPanel, 'push');
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.RemoveButton = uibutton(app.DigitalFilterSettingsPanel, 'push');
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove';
            app.FilterTypeDropDownLabel = uilabel(app.DigitalFilterSettingsPanel);
            app.FilterTypeDropDownLabel.Enable = 'off';
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.DigitalFilterSettingsPanel);
            app.FilterTypeDropDown.Items = {'Hanning', 'Butter'};
            app.FilterTypeDropDown.Enable = 'off';
            app.FilterTypeDropDown.Value = 'Hanning';
            app.LowCutOffHzEditFieldLabel = uilabel(app.DigitalFilterSettingsPanel);
            app.LowCutOffHzEditFieldLabel.Enable = 'off';
            app.LowCutOffHzEditFieldLabel.Text = 'Low Cut-Off (Hz)';
            app.LowCutOffHzEditField = uieditfield(app.DigitalFilterSettingsPanel, 'numeric');
            app.LowCutOffHzEditField.Enable = 'off';
            app.LowCutOffHzEditField.Value = 300;
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.UIAxes2 = uiaxes(app.DetectionTab);
            title(app.UIAxes2, 'Sample Waveform')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            app.UIAxes3 = uiaxes(app.DetectionTab);
            title(app.UIAxes3, 'Spike Waveforms')
            xlabel(app.UIAxes3, 'Time (ms)')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes4 = uiaxes(app.DetectionTab);
            title(app.UIAxes4, 'Spike Amplitude')
            xlabel(app.UIAxes4, 'Count (N)')
            ylabel(app.UIAxes4, 'Amplitude')
            app.DetectionSettingsPanel = uipanel(app.DetectionTab);
            app.DetectionSettingsPanel.Title = 'Detection Settings';
            app.AlignmentDropDownLabel = uilabel(app.DetectionSettingsPanel);
            app.AlignmentDropDownLabel.Enable = 'off';
            app.AlignmentDropDownLabel.Text = 'Alignment';
            app.AlignmentDropDown = uidropdown(app.DetectionSettingsPanel);
            app.AlignmentDropDown.Items = {'Threshold', 'Peaks'};
            app.AlignmentDropDown.Enable = 'off';
            app.AlignmentDropDown.Value = 'Peaks';
            app.ThresholdDropDownLabel = uilabel(app.DetectionSettingsPanel);
            app.ThresholdDropDownLabel.Enable = 'off';
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.DetectionSettingsPanel);
            app.ThresholdDropDown.Items = {'Mean +/- S.D.', 'Smart Threshold', 'Basic Threshold'};
            app.ThresholdDropDown.Enable = 'off';
            app.ThresholdDropDown.Value = 'Smart Threshold';
            app.SDFactorEditFieldLabel = uilabel(app.DetectionSettingsPanel);
            app.SDFactorEditFieldLabel.Enable = 'off';
            app.SDFactorEditFieldLabel.Text = 'S.D. Factor';
            app.SDFactorEditField = uieditfield(app.DetectionSettingsPanel, 'numeric');
            app.SDFactorEditField.Enable = 'off';
            app.ThresholdforPeaksEditFieldLabel = uilabel(app.DetectionSettingsPanel);
            app.ThresholdforPeaksEditFieldLabel.Enable = 'off';
            app.ThresholdforPeaksEditFieldLabel.Text = 'Threshold for Peaks';
            app.ThresholdforPeaksEditField = uieditfield(app.DetectionSettingsPanel, 'numeric');
            app.ThresholdforPeaksEditField.Enable = 'off';
            app.ThresholdforNoiseEditFieldLabel = uilabel(app.DetectionSettingsPanel);
            app.ThresholdforNoiseEditFieldLabel.Enable = 'off';
            app.ThresholdforNoiseEditFieldLabel.Text = 'Threshold for Noise';
            app.ThresholdforNoiseEditField = uieditfield(app.DetectionSettingsPanel, 'numeric');
            app.ThresholdforNoiseEditField.Enable = 'off';
            app.DoDetectButton = uibutton(app.DetectionSettingsPanel, 'push');
            app.DoDetectButton.Enable = 'off';
            app.DoDetectButton.Text = 'Do Detect';
            app.ImportTimesButton = uibutton(app.DetectionSettingsPanel, 'push');
            app.ImportTimesButton.Text = 'Import Times...';
            app.ClusteringTab = uitab(app.TabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.UIAxes5 = uiaxes(app.ClusteringTab);
            title(app.UIAxes5, 'Spike Clustering')
            xlabel(app.UIAxes5, 'PC1')
            ylabel(app.UIAxes5, 'PC2')
            app.UIAxes6 = uiaxes(app.ClusteringTab);
            title(app.UIAxes6, 'Clustering of Simulated Data')
            xlabel(app.UIAxes6, 'PC1')
            ylabel(app.UIAxes6, 'PC2')
            app.UIAxes7 = uiaxes(app.ClusteringTab);
            title(app.UIAxes7, '% ISI Violations')
            xlabel(app.UIAxes7, 'Interspike Interval')
            ylabel(app.UIAxes7, 'Count (%)')
            app.ClusteringOptimizationSettingsPanel = uipanel(app.ClusteringTab);
            app.ClusteringOptimizationSettingsPanel.Title = 'Clustering & Optimization Settings';
            app.NumberofClustersNeighborsEditFieldLabel = uilabel(app.ClusteringOptimizationSettingsPanel);
            app.NumberofClustersNeighborsEditFieldLabel.Enable = 'off';
            app.NumberofClustersNeighborsEditFieldLabel.Text = 'Number of Clusters/Neighbors';
            app.NumberofClustersNeighborsEditField = uieditfield(app.ClusteringOptimizationSettingsPanel, 'numeric');
            app.NumberofClustersNeighborsEditField.Enable = 'off';
            app.NumberofClustersNeighborsEditField.Value = 2;
            app.DoClusteringButton = uibutton(app.ClusteringOptimizationSettingsPanel, 'push');
            app.DoClusteringButton.Enable = 'off';
            app.DoClusteringButton.Text = 'Do Clustering';
            app.ClusteringMethodDropDownLabel = uilabel(app.ClusteringOptimizationSettingsPanel);
            app.ClusteringMethodDropDownLabel.Enable = 'off';
            app.ClusteringMethodDropDownLabel.Text = 'Clustering Method';
            app.ClusteringMethodDropDown = uidropdown(app.ClusteringOptimizationSettingsPanel);
            app.ClusteringMethodDropDown.Items = {'k-Means', 'k-Nearest Neighbors', 'Draw by Hand'};
            app.ClusteringMethodDropDown.Enable = 'off';
            app.ClusteringMethodDropDown.Value = 'k-Means';
            app.OptimizationMethodDropDownLabel = uilabel(app.ClusteringOptimizationSettingsPanel);
            app.OptimizationMethodDropDownLabel.Enable = 'off';
            app.OptimizationMethodDropDownLabel.Text = 'Optimization Method';
            app.OptimizationMethodDropDown = uidropdown(app.ClusteringOptimizationSettingsPanel);
            app.OptimizationMethodDropDown.Items = {'False Pos. < 1%', 'False Pos. = False Neg.'};
            app.OptimizationMethodDropDown.Enable = 'off';
            app.OptimizationMethodDropDown.Value = 'False Pos. < 1%';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.ClusterTrackingPanel = uipanel(app.TrackingTab);
            app.ClusterTrackingPanel.Title = 'Cluster Tracking';
            app.UIAxes9_4 = uiaxes(app.ClusterTrackingPanel);
            title(app.UIAxes9_4, 'Clusters in PC Space')
            xlabel(app.UIAxes9_4, 'PC1')
            ylabel(app.UIAxes9_4, 'PC2')
            app.UIAxes9_5 = uiaxes(app.ClusterTrackingPanel);
            title(app.UIAxes9_5, 'Cluster Stats')
            xlabel(app.UIAxes9_5, '% Overlap')
            ylabel(app.UIAxes9_5, '% ISI Viol')
            app.CentersCheckBox = uicheckbox(app.ClusterTrackingPanel);
            app.CentersCheckBox.Enable = 'off';
            app.CentersCheckBox.Text = 'Centers';
            app.CentersCheckBox.Value = true;
            app.EllipsesCheckBox = uicheckbox(app.ClusterTrackingPanel);
            app.EllipsesCheckBox.Enable = 'off';
            app.EllipsesCheckBox.Text = 'Ellipses';
            app.EllipsesCheckBox.Value = true;
            app.LabelsCheckBox = uicheckbox(app.ClusterTrackingPanel);
            app.LabelsCheckBox.Enable = 'off';
            app.LabelsCheckBox.Text = 'Labels';
            app.LabelsCheckBox.Value = true;
            app.UpdateButton = uibutton(app.ClusterTrackingPanel, 'push');
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.SelectClustersListBoxLabel = uilabel(app.ClusterTrackingPanel);
            app.SelectClustersListBoxLabel.Enable = 'off';
            app.SelectClustersListBoxLabel.Text = 'Select Clusters';
            app.SelectClustersListBox = uilistbox(app.ClusterTrackingPanel);
            app.SelectClustersListBox.Items = {'Cluster 1', 'Cluster 2'};
            app.SelectClustersListBox.Multiselect = 'on';
            app.SelectClustersListBox.Enable = 'off';
            app.SelectClustersListBox.Value = {'Cluster 1'};
            app.ClusterComparisonPanel = uipanel(app.TrackingTab);
            app.ClusterComparisonPanel.Title = 'Cluster Comparison';
            app.UIAxes9_6 = uiaxes(app.ClusterComparisonPanel);
            title(app.UIAxes9_6, 'Axis 1')
            xlabel(app.UIAxes9_6, 'PC1')
            ylabel(app.UIAxes9_6, 'PC2')
            app.UIAxes9_7 = uiaxes(app.ClusterComparisonPanel);
            title(app.UIAxes9_7, 'Axis 2')
            xlabel(app.UIAxes9_7, 'PC1')
            ylabel(app.UIAxes9_7, 'PC2')
            app.CentersCheckBox_2 = uicheckbox(app.ClusterComparisonPanel);
            app.CentersCheckBox_2.Enable = 'off';
            app.CentersCheckBox_2.Text = 'Centers';
            app.CentersCheckBox_2.Value = true;
            app.EllipsesCheckBox_2 = uicheckbox(app.ClusterComparisonPanel);
            app.EllipsesCheckBox_2.Enable = 'off';
            app.EllipsesCheckBox_2.Text = 'Ellipses';
            app.LabelsCheckBox_2 = uicheckbox(app.ClusterComparisonPanel);
            app.LabelsCheckBox_2.Enable = 'off';
            app.LabelsCheckBox_2.Text = 'Labels';
            app.LabelsCheckBox_2.Value = true;
            app.UpdateButton_2 = uibutton(app.ClusterComparisonPanel, 'push');
            app.UpdateButton_2.Enable = 'off';
            app.UpdateButton_2.Text = 'Update';
            app.Axis1DropDownLabel = uilabel(app.ClusterComparisonPanel);
            app.Axis1DropDownLabel.Enable = 'off';
            app.Axis1DropDownLabel.Text = 'Axis 1';
            app.Axis1DropDown = uidropdown(app.ClusterComparisonPanel);
            app.Axis1DropDown.Items = {'Cluster 1', 'Cluster 2'};
            app.Axis1DropDown.Enable = 'off';
            app.Axis1DropDown.Value = 'Cluster 1';
            app.Axis2DropDownLabel = uilabel(app.ClusterComparisonPanel);
            app.Axis2DropDownLabel.Enable = 'off';
            app.Axis2DropDownLabel.Text = 'Axis 2';
            app.Axis2DropDown = uidropdown(app.ClusterComparisonPanel);
            app.Axis2DropDown.Items = {'Cluster 1', 'Cluster 2'};
            app.Axis2DropDown.Enable = 'off';
            app.Axis2DropDown.Value = 'Cluster 2';
            app.LinkAxesScaleCheckBox = uicheckbox(app.ClusterComparisonPanel);
            app.LinkAxesScaleCheckBox.Enable = 'off';
            app.LinkAxesScaleCheckBox.Text = 'Link Axes Scale';
            app.VerificationTab = uitab(app.TabGroup);
            app.VerificationTab.Title = 'Verification';
            app.UIAxes5_2 = uiaxes(app.VerificationTab);
            title(app.UIAxes5_2, 'Percent Error by File')
            xlabel(app.UIAxes5_2, 'File Number')
            ylabel(app.UIAxes5_2, 'Percent Error (%)')
            app.UIAxes5_3 = uiaxes(app.VerificationTab);
            title(app.UIAxes5_3, '1 ms ISI Violations (%)')
            xlabel(app.UIAxes5_3, 'File Number')
            ylabel(app.UIAxes5_3, 'Spike Count (%)')
            app.UIAxes5_4 = uiaxes(app.VerificationTab);
            title(app.UIAxes5_4, 'Number of Spikes')
            xlabel(app.UIAxes5_4, 'File Number')
            ylabel(app.UIAxes5_4, 'Spike Count')
            app.UIAxes5_5 = uiaxes(app.VerificationTab);
            title(app.UIAxes5_5, 'Compare Statistics')
            xlabel(app.UIAxes5_5, 'Spike Count')
            ylabel(app.UIAxes5_5, 'ISI Viols (%)')
            app.xAxisDropDownLabel = uilabel(app.VerificationTab);
            app.xAxisDropDownLabel.Enable = 'off';
            app.xAxisDropDownLabel.Text = 'x-Axis';
            app.xAxisDropDown = uidropdown(app.VerificationTab);
            app.xAxisDropDown.Items = {'% Error', 'ISI Viols', 'Spike Count', 'p-Value', 'File Number'};
            app.xAxisDropDown.Enable = 'off';
            app.xAxisDropDown.Value = 'Spike Count';
            app.yAxisDropDownLabel = uilabel(app.VerificationTab);
            app.yAxisDropDownLabel.Enable = 'off';
            app.yAxisDropDownLabel.Text = 'y-Axis';
            app.yAxisDropDown = uidropdown(app.VerificationTab);
            app.yAxisDropDown.Items = {'% Error', 'ISI Viols', 'Spike Count', 'p-Value', 'File Number'};
            app.yAxisDropDown.Enable = 'off';
            app.yAxisDropDown.Value = 'ISI Viols';
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.UIAxes8 = uiaxes(app.ResultTab);
            title(app.UIAxes8, 'Waveform')
            xlabel(app.UIAxes8, 'Time (s)')
            ylabel(app.UIAxes8, 'Amplitude')
            app.DataFileListBoxLabel = uilabel(app.ResultTab);
            app.DataFileListBoxLabel.Enable = 'off';
            app.DataFileListBoxLabel.Text = 'Data File';
            app.DataFileListBox = uilistbox(app.ResultTab);
            app.DataFileListBox.Items = {'File 1', 'File 2', 'File 3', 'File 4', 'File 5'};
            app.DataFileListBox.Enable = 'off';
            app.DataFileListBox.Value = 'File 1';
            app.UpdatePlotButton = uibutton(app.ResultTab, 'push');
            app.UpdatePlotButton.Enable = 'off';
            app.UpdatePlotButton.Text = 'Update Plot';
            app.ThresholdCheckBox = uicheckbox(app.ResultTab);
            app.ThresholdCheckBox.Enable = 'off';
            app.ThresholdCheckBox.Text = 'Threshold';
            app.ThresholdCheckBox.Value = true;
            app.SpikeRasterCheckBox = uicheckbox(app.ResultTab);
            app.SpikeRasterCheckBox.Enable = 'off';
            app.SpikeRasterCheckBox.Text = 'Spike Raster';
            app.SpikeRasterCheckBox.Value = true;
            app.ISIViolationsCheckBox = uicheckbox(app.ResultTab);
            app.ISIViolationsCheckBox.Enable = 'off';
            app.ISIViolationsCheckBox.Text = 'ISI Violations';
            app.ClustersListBoxLabel = uilabel(app.ResultTab);
            app.ClustersListBoxLabel.Enable = 'off';
            app.ClustersListBoxLabel.Text = 'Clusters';
            app.ClustersListBox = uilistbox(app.ResultTab);
            app.ClustersListBox.Items = {'Cluster 1', 'Cluster 2'};
            app.ClustersListBox.Multiselect = 'on';
            app.ClustersListBox.Enable = 'off';
            app.ClustersListBox.Value = {'Cluster 1'};
            app.StatusPanel = uipanel(app.SAUCYUIFigure);
            app.StatusPanel.Title = 'Status';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.StatusText = uilabel(app.StatusPanel);
            app.StatusText.Text = 'SAUCY status bar [not yet configured]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SAUCY_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAUCYUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAUCYUIFigure)
        end
    end
end